classdef substormStatistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        titleLabel                matlab.ui.container.Panel
        AESliderLabel             matlab.ui.control.Label
        AESlider                  matlab.ui.control.Slider
        deltaAESliderLabel        matlab.ui.control.Label
        deltaAESlider             matlab.ui.control.Slider
        ColorBarMaxSliderLabel    matlab.ui.control.Label
        ColorBarMaxSlider         matlab.ui.control.Slider
        UIAxes                    matlab.ui.control.UIAxes
        AErangeLabel              matlab.ui.control.Label
        authorNameLabel           matlab.ui.control.Label
        PokerFlatSwitchLabel      matlab.ui.control.Label
        PokerFlatSwitch           matlab.ui.control.ToggleSwitch
        Upperdelta_MLATKnobLabel  matlab.ui.control.Label
        Upperdelta_MLATKnob       matlab.ui.control.Knob
        delta_MLONKnobLabel       matlab.ui.control.Label
        delta_MLONKnob            matlab.ui.control.Knob
    end

    
    properties (Access = public)
        superMag;
        ax;
        dr=1.1;
    end
    
    methods (Access = public)
        function indicate_AE(app)
        plot(app.UIAxes,[app.AESlider.Value, app.AESlider.Value+app.deltaAESlider.Value],[1,1],'-','LineWidth',5);
        app.UIAxes.YColor='none';
        app.UIAxes.XLim = [0,1400];
        app.UIAxes.XAxis.TickValues=[0,1400];
%        app.UIAxes.XLabel.String=strcat('AE = ',num2str(app.AESlider.Value),' dAE = ',num2str(app.deltaAESlider.Value));
        app.UIAxes.Title.String=strcat('AE = ',num2str(app.AESlider.Value),' dAE = ',num2str(app.deltaAESlider.Value));
        end
    
        function plot_substorm_statistics(app)
            AE = app.AESlider.Value;
            dAE = app.deltaAESlider.Value;
            Dmlat = app.Upperdelta_MLATKnob.Value;
            Dmlt = app.delta_MLONKnob.Value;
            desiredMLATIndx = app.superMag.mlat<app.superMag.pfisrMlat+Dmlat;
            deltaMLT = mod(app.superMag.pfisrMlt - app.superMag.mlt,24);
            desiredMLTIndx = abs(deltaMLT)<Dmlt;
            closestSubstormIndx = desiredMLTIndx & desiredMLATIndx; 
            
            filterIndx = app.superMag.AE>AE & app.superMag.AE<AE+dAE & (closestSubstormIndx | ~strcmp(app.PokerFlatSwitch.Value,'On'));
            
            app.ax.clo;
            h=histogram2Polar(deg2rad(app.superMag.mlt(filterIndx)*360./24),...
                90-(app.superMag.mlat(filterIndx)),app.dr,...
                'RTicks',90-[90,80,70,60,50],'RLim',(90-[90,40]),'RAxisLocation',180,...
                'Parent',app.ax);
            h.RAxisLabels(1).String='90';
            h.RAxisLabels(2).String='80';
            h.RAxisLabels(3).String='70';
            h.RAxisLabels(4).String='60';
            h.RAxisLabels(5).String='50';
            h.ThetaAxisLabels(1).String='00';
            h.ThetaAxisLabels(2).String='06';
            h.ThetaAxisLabels(3).String='12';
            h.ThetaAxisLabels(4).String='18';
            h.ThetaZeroLocation='bottom';
            caxis(app.ax,[0 app.ColorBarMaxSlider.Value]);
            %h.Colorbar.Limits(2) = app.ColorBarMaxSlider.Value;
            colormap(app.ax,inferno);
            h.Colorbar.Label.String=['Samples per ', num2str(app.dr),'° grid'];
            set(app.ax,'ColorScale','linear');
        end
        
        function superMag = load_data(app,loadDataFileStr)
                load(loadDataFileStr);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, loadDataFileStr)
            if nargin<2 || isempty(loadDataFileStr)
                loadDataFileStr="G:\My Drive\Research\Projects\Paper 3\Data\table_of_substorms_full.mat";
            end
            AE=app.AESlider.Value;
            app.superMag = load_data(app,loadDataFileStr);
            app.ax=axes(app.titleLabel);
            set(app.ax,'XColor', 'none','YColor','none');
            Dmlat = app.Upperdelta_MLATKnob.Value;
            Dmlt = app.delta_MLONKnob.Value;
            desiredMLATIndx = app.superMag.mlat<app.superMag.pfisrMlat+Dmlat;
            deltaMLT = mod(app.superMag.pfisrMlt - app.superMag.mlt,24);
            desiredMLTIndx = abs(deltaMLT)<Dmlt;
            closestSubstormIndx = desiredMLTIndx & desiredMLATIndx; 
            filterIndx = app.superMag.AE>AE & app.superMag.AE<AE+app.deltaAESlider.Value...
                & (closestSubstormIndx | ~strcmp(app.PokerFlatSwitch.Value,'On'));
            h=histogram2Polar(deg2rad(app.superMag.mlt(filterIndx)*360./24),...
                90-(app.superMag.mlat(filterIndx)),app.dr,...
                'RTicks',90-[90,80,70,60,50],'RLim',90-[90,40],'RAxisLocation',180,...
                'Parent',app.ax);
            h.RAxisLabels(1).String='90';
            h.RAxisLabels(2).String='80';
            h.RAxisLabels(3).String='70';
            h.RAxisLabels(4).String='60';
            h.RAxisLabels(5).String='50';
            h.ThetaAxisLabels(1).String='00';
            h.ThetaAxisLabels(2).String='06';
            h.ThetaAxisLabels(3).String='12';
            h.ThetaAxisLabels(4).String='18';
            h.ThetaZeroLocation='bottom';
            caxis(app.ax,[0 app.ColorBarMaxSlider.Value]);
            h.Colorbar.Label.String=['Samples per ', num2str(app.dr),'° grid'];
            colormap(app.ax,inferno);
            set(app.ax,'ColorScale','linear');
            indicate_AE(app)
        end

        % Value changed function: AESlider
        function AESliderValueChanged(app, event)
            plot_substorm_statistics(app)
            indicate_AE(app)
        end

        % Value changed function: deltaAESlider
        function deltaAESliderValueChanged(app, event)
            plot_substorm_statistics(app)
            indicate_AE(app)
        end

        % Value changed function: ColorBarMaxSlider
        function ColorBarMaxSliderValueChanged(app, event)
            plot_substorm_statistics(app)
        end

        % Value changed function: PokerFlatSwitch
        function PokerFlatSwitchValueChanged(app, event)
            plot_substorm_statistics(app)
            if strcmp(app.PokerFlatSwitch.Value,'On')
                text(app.ax,-0.1,0.9,'PFISR: ON', 'Units','normalized','BackgroundColor',[0.5,1,0.5]);
            end
        end

        % Value changed function: Upperdelta_MLATKnob
        function Upperdelta_MLATKnobValueChanged(app, event)
            if strcmp(app.PokerFlatSwitch.Value,'On')
                plot_substorm_statistics(app)
                text(app.ax,-0.1,0.9,'PFISR: ON', 'Units','normalized','BackgroundColor',[0.5,1,0.5]);
            end
        end

        % Value changed function: delta_MLONKnob
        function delta_MLONKnobValueChanged(app, event)
            if strcmp(app.PokerFlatSwitch.Value,'On')
                plot_substorm_statistics(app)
                text(app.ax,-0.1,0.9,'PFISR: ON', 'Units','normalized','BackgroundColor',[0.5,1,0.5]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.titleLabel = uipanel(app.UIFigure);
            app.titleLabel.Title = 'SuperMag Substorms from 1 Jan 2006 to 31 Dec 2018';
            app.AESliderLabel = uilabel(app.UIFigure);
            app.AESliderLabel.Text = 'AE';
            app.AESlider = uislider(app.UIFigure);
            app.AESlider.Limits = [0 1400];
            app.AESlider.ValueChangedFcn = createCallbackFcn(app, @AESliderValueChanged, true);
            app.deltaAESliderLabel = uilabel(app.UIFigure);
            app.deltaAESliderLabel.Text = 'delta AE';
            app.deltaAESlider = uislider(app.UIFigure);
            app.deltaAESlider.Limits = [0 1400];
            app.deltaAESlider.ValueChangedFcn = createCallbackFcn(app, @deltaAESliderValueChanged, true);
            app.deltaAESlider.Value = 1400;
            app.ColorBarMaxSliderLabel = uilabel(app.UIFigure);
            app.ColorBarMaxSliderLabel.Text = {'ColorBar'; 'Max'};
            app.ColorBarMaxSlider = uislider(app.UIFigure);
            app.ColorBarMaxSlider.Limits = [0 200];
            app.ColorBarMaxSlider.ValueChangedFcn = createCallbackFcn(app, @ColorBarMaxSliderValueChanged, true);
            app.ColorBarMaxSlider.Value = 100;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AErangeLabel = uilabel(app.UIFigure);
            app.AErangeLabel.Text = 'AE range';
            app.authorNameLabel = uilabel(app.UIFigure);
            app.authorNameLabel.Text = {'Nithin Sivadas'; 'Boston University'};
            app.PokerFlatSwitchLabel = uilabel(app.UIFigure);
            app.PokerFlatSwitchLabel.Text = 'Poker Flat';
            app.PokerFlatSwitch = uiswitch(app.UIFigure, 'toggle');
            app.PokerFlatSwitch.ValueChangedFcn = createCallbackFcn(app, @PokerFlatSwitchValueChanged, true);
            app.Upperdelta_MLATKnobLabel = uilabel(app.UIFigure);
            app.Upperdelta_MLATKnobLabel.Text = 'Upper delta_MLAT';
            app.Upperdelta_MLATKnob = uiknob(app.UIFigure, 'continuous');
            app.Upperdelta_MLATKnob.Limits = [0 25];
            app.Upperdelta_MLATKnob.ValueChangedFcn = createCallbackFcn(app, @Upperdelta_MLATKnobValueChanged, true);
            app.Upperdelta_MLATKnob.Value = 1;
            app.delta_MLONKnobLabel = uilabel(app.UIFigure);
            app.delta_MLONKnobLabel.Text = 'delta_MLON';
            app.delta_MLONKnob = uiknob(app.UIFigure, 'continuous');
            app.delta_MLONKnob.Limits = [0 10];
            app.delta_MLONKnob.ValueChangedFcn = createCallbackFcn(app, @delta_MLONKnobValueChanged, true);
            app.delta_MLONKnob.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = substormStatistics(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end