classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        fractionalErrorPlane  matlab.ui.control.UIAxes
        SpectraWidthLabel     matlab.ui.control.Label
        spectralWidthSlider   matlab.ui.control.Slider
        sliceAtSNR            matlab.ui.control.UIAxes
        sliceAtnPulseTrains   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [F,x,y,bar] = func(app)
            load('G:\My Drive\Research\Research Trips\2018 Mar San Francisco SRI\Scrap\monteCarloDataV2.mat','data');
            xData = data.exp.nPulseTrains(:);
            yData = data.exp.SNR(:);
            barData = data.exp.decorrelationTime(:).^-1;  
            cData = data.fracError.SNR(:);
%             data.mean.SNR = data.mean.signal./data.mean.noise;
%             data.fracError.signal = data.std.signal./data.mean.signal;
%             data.fracError.noise = data.std.noise./data.mean.noise;
%             data.fracError.SNR = data.fracError.signal+abs(data.fracError.noise);
            F = scatteredInterpolant(xData,yData,barData,...
                cData,'nearest','none');
            x = unique(xData);
            y = unique(yData);
            bar = unique(barData);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changing function: spectralWidthSlider
        function spectralWidthSliderValueChanging(app, event)
            changingValue = event.Value;
            %IntegrationTime = 0.5:0.1:5;
            %y = unique(data.exp.SNR);
            
            %             value = app.spectralWidthSlider.Value;
            
            zfWidth = changingValue;
                        
            if ~exist('F','var')
                [F,x,y,bar] = func(app);
            end
            [X,Y,Z] = meshgrid(x,y,zfWidth);
            fracError = F(X,Y,Z);
            
            h=pcolor(app.fractionalErrorPlane,X,Y,fracError);
            nx = length(x);
            fracErrorx = F(x,0.3*ones(nx,1),zfWidth*ones(nx,1));
            plot(app.sliceAtSNR,x,fracErrorx);
            app.sliceAtSNR.YLim=[0 0.5];app.sliceAtSNR.XLim=[1 20];
            app.sliceAtSNR.XLabel.String='K - number of pulse trains';
            app.sliceAtSNR.YLabel.String='Fractional Error in SNR';
            ny = length(y);
            fracErrory = F(10*ones(ny,1),y,zfWidth*ones(ny,1));
            plot(app.sliceAtnPulseTrains,y,fracErrory);
            app.sliceAtnPulseTrains.YLim=[0 0.5];app.sliceAtnPulseTrains.XLim=[0 1];
            app.sliceAtSNR.XLabel.String='SNR [Ratio]';
            app.sliceAtSNR.YLabel.String='Fractional Error in SNR';
            
            app.fractionalErrorPlane.XLabel.String='K - number of pulse trains';
            app.fractionalErrorPlane.YLabel.String='SNR [ratio]';
            
            set(h, 'EdgeColor', 'none');
            app.fractionalErrorPlane.Colormap=flipud(gray);
            
            app.fractionalErrorPlane.CLim=[0 0.5];
            colorbar(app.fractionalErrorPlane)
            
            
            %             h1 = colorbar;
            %             ylabel(h1, 'Fractional Error in SNR')
            %             title(['Spectral Width = ', num2str(zfWidth),' Hz']);
            xlim = [min(x) max(x)];
            ylim = [min(y) 1];
            app.fractionalErrorPlane.YLim = ylim;
            app.fractionalErrorPlane.XLim = xlim;
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.fractionalErrorPlane = uiaxes(app.UIFigure);
            title(app.fractionalErrorPlane, 'Fractional Error in SNR')
            xlabel(app.fractionalErrorPlane, 'K - no. of. pulse trains')
            ylabel(app.fractionalErrorPlane, 'SNR')
            app.SpectraWidthLabel = uilabel(app.UIFigure);
            app.SpectraWidthLabel.Text = 'Spectra Width';
            app.spectralWidthSlider = uislider(app.UIFigure);
            app.spectralWidthSlider.Limits = [1 100];
            app.spectralWidthSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 100];
            app.spectralWidthSlider.ValueChangingFcn = createCallbackFcn(app, @spectralWidthSliderValueChanging, true);
            app.spectralWidthSlider.Value = 30;
            app.sliceAtSNR = uiaxes(app.UIFigure);
            title(app.sliceAtSNR, 'Slice at 0.3 SNR')
            xlabel(app.sliceAtSNR, 'K - number of pulse trains')
            ylabel(app.sliceAtSNR, 'Fractional Error in SNR')
            app.sliceAtnPulseTrains = uiaxes(app.UIFigure);
            title(app.sliceAtnPulseTrains, 'Slice at K = 10')
            xlabel(app.sliceAtnPulseTrains, 'SNR')
            ylabel(app.sliceAtnPulseTrains, 'Fractional Error in SNR')
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end